classdef qMRLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        qMRILab                  matlab.ui.Figure
        Image                    matlab.ui.control.Image
        upgrade_message          matlab.ui.control.Label
        MethodSelection          matlab.ui.control.DropDown
        OpenOptionsPanel         matlab.ui.control.Button
        DefaultMethodBtn         matlab.ui.control.Button
        uipanel37                matlab.ui.container.Panel
        FitGO                    matlab.ui.control.Button
        FitResultsSave           matlab.ui.control.Button
        FitResultsLoad           matlab.ui.control.Button
        SimPanel                 matlab.ui.container.Panel
        SimGO                    matlab.ui.control.Button
        SimLoad                  matlab.ui.control.Button
        SimSave                  matlab.ui.control.Button
        SimRndBtn                matlab.ui.control.Button
        SimVaryBtn               matlab.ui.control.Button
        SimCurveBtn              matlab.ui.control.Button
        FitDataPanel             matlab.ui.container.Panel
        text_doc_model           matlab.ui.control.Label
        CurrentFitId             matlab.ui.control.Label
        text53                   matlab.ui.control.Label
        FitDataFileBrowserPanel  matlab.ui.container.Panel
        FitResultsPlotPanel      matlab.ui.container.Panel
        text80_2                 matlab.ui.control.Label
        ViewROIFit               matlab.ui.control.Button
        Stats                    matlab.ui.control.Button
        txt_OrientS              matlab.ui.control.Label
        txt_OrientI              matlab.ui.control.Label
        txt_OrientR              matlab.ui.control.Label
        txt_OrientL              matlab.ui.control.Label
        text80                   matlab.ui.control.Label
        Viewer                   matlab.ui.control.Button
        text72                   matlab.ui.control.Label
        ViewDataFit              matlab.ui.control.Button
        CursorBtn                matlab.ui.control.StateButton
        SourcePop                matlab.ui.control.DropDown
        ViewPop                  matlab.ui.control.DropDown
        Histogram                matlab.ui.control.Button
        text_version_check       matlab.ui.control.Label
    end


    methods (Access = private)
        function isDark = isSystemDarkMode(app)
            % Detect if system is in dark mode (Windows/Mac)
            if ispc
                % Windows registry check for dark mode
                try
                    [status, result] = system('reg query HKCU\SOFTWARE\Microsoft\Windows\CurrentVersion\Themes\Personalize /v AppsUseLightTheme');
                    if status == 0
                        isDark = contains(result, '0x0');
                    else
                        isDark = false;
                    end
                catch
                    isDark = false;
                end
            elseif ismac
                % macOS dark mode detection
                try
                    [status, result] = system('defaults read -g AppleInterfaceStyle');
                    isDark = status == 0 && contains(result, 'Dark');
                catch
                    isDark = false;
                end
            else
                % Linux or unknown - use default light mode
                isDark = false;
            end
        end

        function colors = getColorScheme(app)
            % Return appropriate color scheme based on system theme
            if app.isSystemDarkMode()
                % Dark mode colors
                colors.background = [0.15 0.15 0.15];
                colors.foreground = [0.9 0.9 0.9];
                colors.panelBg = [0.2 0.2 0.2];
                colors.panelFg = [0.95 0.95 0.95];
                colors.buttonBg = [0.3 0.3 0.3];
                colors.buttonFg = [0.95 0.95 0.95];
                colors.accent = [0.149 0.549 0.8667];
                colors.warning = [1 0.6 0.2];
                colors.success = [0.2 0.8 0.2];
            else
                % Light mode colors (your existing colors)
                colors.background = [0.9412 0.9412 0.9412];
                colors.foreground = [0 0 0];
                colors.panelBg = [1 1 1];
                colors.panelFg = [0 0 0];
                colors.buttonBg = [0.902 0.902 0.902];
                colors.buttonFg = [0 0 0];
                colors.accent = [0.149 0.549 0.8667];
                colors.warning = [1 0.3294 0.3294];
                colors.success = [0.2 0.8 0.2];
            end
        end

        function updateLogo(app)
            % Switch logo based on current theme
            pathToMLAPP = fileparts(mfilename('fullpath'));

            if app.isSystemDarkMode()
                darkLogoPath = fullfile(pathToMLAPP, 'logo_dark.png');
                lightLogoPath = fullfile(pathToMLAPP, 'logo_light.png');

                % Check if dark logo exists, otherwise use light logo with warning
                if exist(darkLogoPath, 'file')
                    app.Image.ImageSource = darkLogoPath;
                else
                    app.Image.ImageSource = lightLogoPath;
                    warning('Dark mode logo not found. Using light logo instead.');
                end
            else
                lightLogoPath = fullfile(pathToMLAPP, 'logo_light.png');
                app.Image.ImageSource = lightLogoPath;
            end
        end

        function applyTheme(app)
            % Apply the current theme to all components
            colors = app.getColorScheme();

            app.updateLogo();


            % Apply to main figure
            app.qMRILab.Color = colors.background;
            app.uipanel37.BackgroundColor = colors.background;
            app.uipanel37.ForegroundColor = colors.foreground;

            % Apply to panels
            panelComponents = [app.FitDataPanel, app.FitResultsPlotPanel, ...
                app.FitDataFileBrowserPanel, app.SimPanel];
            for i = 1:length(panelComponents)
                panelComponents(i).BackgroundColor = colors.panelBg;
                panelComponents(i).ForegroundColor = colors.panelFg;
            end
            app.text_doc_model.BackgroundColor = colors.panelBg;

            % Apply to buttons
            buttonComponents = [app.FitGO, app.FitResultsSave, app.FitResultsLoad, ...
                app.SimGO, app.SimSave, app.SimLoad, app.SimRndBtn, ...
                app.SimVaryBtn, app.SimCurveBtn, app.DefaultMethodBtn, ...
                app.OpenOptionsPanel, app.ViewDataFit, app.ViewROIFit, ...
                app.Viewer, app.CursorBtn, app.Stats, app.Histogram];
            for i = 1:length(buttonComponents)
                if ~isequal(buttonComponents(i).BackgroundColor, [1 0.3294 0.3294]) && ...
                        ~isequal(buttonComponents(i).BackgroundColor, [0.149 0.549 0.8667])
                    % Only change non-accent buttons
                    buttonComponents(i).BackgroundColor = colors.buttonBg;
                    buttonComponents(i).FontColor = colors.buttonFg;
                end
            end

            % Apply to labels and text
            labelComponents = [app.text_version_check, app.text53, app.CurrentFitId, ...
                app.text_doc_model, app.upgrade_message, app.text72, ...
                app.text80, app.txt_OrientL, app.txt_OrientR, ...
                app.txt_OrientI, app.txt_OrientS, app.text80_2];
            for i = 1:length(labelComponents)
                labelComponents(i).FontColor = colors.foreground;
                % Update background for labels that need it
                if isequal(labelComponents(i).BackgroundColor, [0.94 0.94 0.94])
                    labelComponents(i).BackgroundColor = colors.background;
                end
            end
            app.txt_OrientS.BackgroundColor = colors.panelBg;
            app.txt_OrientI.BackgroundColor = colors.panelBg;
            app.txt_OrientR.BackgroundColor = colors.panelBg;
            app.txt_OrientL.BackgroundColor = colors.panelBg;

            % Special handling for documentation link
            app.text_doc_model.FontColor = colors.accent;

            % Update dropdowns
            dropdownComponents = [app.MethodSelection, app.SourcePop, app.ViewPop];
            for i = 1:length(dropdownComponents)
                dropdownComponents(i).BackgroundColor = colors.buttonBg;
                dropdownComponents(i).FontColor = colors.foreground;
            end
        end

        function FitGo_FitData(app, hObject, eventdata, handles)
            % Original FitGo function


            % Get data
            data =  GetAppData(app, 'Data');
            Method = GetAppData(app, 'Method');
            Model = getappdata(0,'Model');
            if isfield(data,[class(Model) '_hdr']), hdr = data.([class(Model) '_hdr']); end
            data = data.(Method);

            % check data
            ErrMsg = Model.sanityCheck(data);
            if ~isempty(ErrMsg), errordlg(ErrMsg,'Input error','modal'); return; end

            if ~moxunit_util_platform_is_octave

                try
                    p = gcp('nocreate');
                catch
                    p=[];
                end
                if license('test','Distrib_Computing_Toolbox') && Model.voxelwise && isempty(p)
                    cprintf('blue', 'MATLAB detected %d physical cores.',feature('numcores'));
                    cprintf('blue', '<< Tip >> You can accelerate fitting by starting a parallel pool by running: \n parpool(%d);',feature('numcores'));
                    dlgTitle    = 'Parallel Processing';
                    dlgQuestion = sprintf('Would you like to start a parallel pool with %d cores?',feature('numcores'));
                    choice = questdlg(dlgQuestion,dlgTitle,'Yes','No', 'Yes');
                    if strcmp(choice,'Yes')
                        parpool(feature('numcores'));
                        p = gcp('nocreate');
                    end
                end

                if ~isempty(p) && Model.voxelwise
                    FitResults = ParFitData(data,Model);
                else
                    FitResults = FitData(data,Model,1);
                end

            else
                % Do the fitting in Octave
                FitResults = FitData(data,Model,1);
            end

            % Save info with results
            FileBrowserList = GetAppData(app, 'FileBrowserList');
            MethodList = getappdata(0, 'MethodList');
            MethodList = strrep(MethodList, '.m', '');
            MethodCount = numel(MethodList);

            for i=1:MethodCount
                if FileBrowserList(i).IsMethodID(Method)
                    MethodID = i;
                end
            end
            FitResults.StudyID = FileBrowserList(MethodID).getStudyID;
            FitResults.WD = FileBrowserList(MethodID).getWD;
            if isempty(FitResults.WD), FitResults.WD = pwd; end
            FitResults.Files = FileBrowserList(MethodID).getFileName;
            SetAppData(app, FitResults);

            % Kill the waitbar in case of a problem occurred
            wh=findall(0,'tag','TMWWaitbar');
            delete(wh);

            % convert Model to struct
            FitResults.Model = objProps2struct(FitResults.Model);

            % Save fit results
            if(~isempty(FitResults.StudyID))
                filename = strcat('FitResults_',FitResults.StudyID,'.mat');
            else
                filename = 'FitResults.mat';
            end
            outputdir = fullfile(FitResults.WD,['FitResults_', datestr(datetime('now','TimeZone','local'),'yyyy-mm-dd_HH-MM-SS')]); % ISO 8601 format adapted for MATLAB compatibility
            if ~exist(outputdir,'dir'), mkdir(outputdir);
            else
                iii=1; outputdirnew = outputdir;
                while exist(outputdirnew,'dir')
                    iii=iii+1;
                    outputdirnew = [outputdir,'_' num2str(iii)];
                end
                outputdir = outputdirnew;
                mkdir(outputdir);
            end
            save(fullfile(outputdir,filename),'-struct','FitResults');
            set(handles.CurrentFitId,'String','FitResults.mat');

            % Save nii maps
            for ii = 1:length(FitResults.fields)
                map = FitResults.fields{ii};
                file = strcat(map,'.nii.gz');

                if ~exist('hdr','var')
                    save_nii(make_nii(FitResults.(map)),fullfile(outputdir,file));
                else
                    % Reset multiplicative and additive scale factors to nifti header
                    % in case there were some in the input file's header that was used
                    % as a template. If this isn't done, then when a tool loads the
                    % qMRI map's nifti file, it will apply an undesired scaling.
                    hdr.scl_slope = 1;
                    hdr.scl_inter = 0;

                    nii_save(FitResults.(map),hdr,fullfile(outputdir,file));
                end
            end

            SetAppData(app, FileBrowserList);
            % Show results
            handles.CurrentData = FitResults;
            if exist('hdr','var')
                handles.CurrentData.hdr = hdr;
            end
            guidata(hObject,handles);
            DrawPlot(handles);
        end

        function varargout = GetAppData(app, varargin)
            % GETAPPDATA - Fixed for App Designer
            % Skip the first argument (app) and process the rest

            nVars = length(varargin);
            varargout = cell(1, nVars);

            for k = 1:nVars
                varargout{k} = getappdata(0, varargin{k});
            end
        end

        function MethodMenu(app, hObject, eventdata, handles, Method)
            % METHODSELECTION


            SetAppData(app, Method)

            % Start by updating the Model object
            if isappdata(0,'Model') && strcmp(class(getappdata(0,'Model')),Method) % if same method, load the current class with parameters
                Model = getappdata(0,'Model');
            else % otherwise create a new object of this method
                Modeltobesaved = getappdata(0,'Model');
                savedModel = getappdata(0,'savedModel');
                savedModel.(class(Modeltobesaved)) = Modeltobesaved;
                setappdata(0,'savedModel',savedModel);
                if isfield(savedModel,Method) && ~isempty(savedModel.(Method))
                    Model = savedModel.(Method);
                else
                    modelfun  = str2func(Method);
                    Model = modelfun();
                end
            end
            SetAppData(app, Model)
            % Create empty Data
            Data = GetAppData(app, 'Data');
            for id=1:length(Model.MRIinputs)
                if isempty(Data) || ~isfield(Data,Method) || ~isfield(Data.(Method),Model.MRIinputs{id})
                    Data.(Method).(Model.MRIinputs{id})=[];
                end
            end
            SetAppData(app, Data);

            % Now create Simulation panel
            % find the Simulation functions of the selected Method
            Methodfun = methods(Method);
            Simfun = Methodfun(~cellfun(@isempty,strfind(Methodfun,'Sim_')));
            % Update Options Panel
            set(handles.SimPanel,'Visible','off') % hide the simulation panel for qMT methods
            if isempty(Simfun)
                set(handles.SimPanel,'Visible','off') % hide the simulation panel
            else
                set(handles.SimPanel,'Visible','on') % show the simulation panel
                delete(setdiff(findobj(handles.SimPanel),handles.SimPanel))

                N = length(Simfun); %
                Jh = min(0.14,.8/N);
                J=1:max(N,6); J=(J-1)/max(N,6)*0.85; J=1-J-Jh-.01;
                for i = 1:N
                    if exist([Simfun{i} '_GUI'],'file')
                        uicontrol('Style','pushbutton','String',strrep(strrep(Simfun{i},'Sim_',''),'_',' '),...
                            'Parent',handles.SimPanel,'Units','normalized','Position',[.04 J(i) .92 Jh],...
                            'HorizontalAlignment','center','FontWeight','bold','Callback',...
                            @(x,y) SimfunGUI(app, [Simfun{i} '_GUI']));
                    end
                end

            end


            % Update Options Panel
            h = findobj('Tag','OptionsGUI');
            if ~isempty(h)
                delete(h);
            end
            Method = app.GetAppData('Method');
            Model = getappdata(0,'Model');
            Custom_OptionsGUI(Model, app.qMRILab);


            % Show FileBrowser - with lazy loading
            FileBrowserList = GetAppData(app, 'FileBrowserList');

            % Hide all browsers
            allMethods = fieldnames(FileBrowserList);
            for i = 1:length(allMethods)
                FileBrowserList.(allMethods{i}).Visible('off');
            end

            % Create browser for current method if it doesn't exist
            if ~isfield(FileBrowserList, Method) || isempty(FileBrowserList.(Method))
                Modelfun = str2func(Method);
                Model = Modelfun();
                FileBrowserList.(Method) = MethodBrowser(app.FitDataFileBrowserPanel, Model);
                SetAppData(app, FileBrowserList);
            end

            % Show current method's browser
            FileBrowserList.(Method).Visible('on');

            % Scale the main panel by a super small factor and
            % bring it back to the original to get rid of
            % artificial duplication of the top portion of the data
            % panel that occurs upon switching to another model
            % after selecting mp2rage.

            % Caused by attachScrollToPanel.

            curpos = get(handles.qMRILab,'Position');
            set(handles.qMRILab,'Position',curpos.*[1 1 1.0001 1.0001]);
            set(handles.qMRILab,'Position',curpos);

            % enable/disable viewdatafit
            if ismethod(Model,'plotModel')
                set(handles.ViewDataFit,'Enable','on')
                set(handles.ViewROIFit,'Enable','on')
                set(handles.ViewDataFit,'TooltipString','View fit in a particular voxel')
                set(handles.ViewROIFit,'TooltipString','View fit in currently selected label')
            else
                set(handles.ViewDataFit,'Enable','off')
                set(handles.ViewROIFit,'Enable','off')
                set(handles.ViewDataFit,'TooltipString','No voxel-wise fitting for this qMR Method (Volume based method)')
            end
            guidata(hObject, handles);
        end

        function Method_Selection_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function PanelOff(app, panel, handles)
            eval(sprintf('set(handles.%sPanel, ''Visible'', ''off'')', panel));
        end

        function PanelOn(app, panel, handles)
            eval(sprintf('set(handles.%sPanel, ''Visible'', ''on'')', panel));
        end

        function RefreshPlot(app, handles)
            if isempty(handles.CurrentData), return; end
            % Apply View
            View = get(handles.ViewPop,'String'); if ~iscell(View), View = {View}; end
            View = View{get(handles.ViewPop,'Value')};
            Data = ApplyView(handles.CurrentData, View);
            % Display
            if isfield(Data,'Mask'), Mask = Data.Mask; else Mask = []; end
            for ff = 1:length(Data.fields)
                Current{ff} = Data.(Data.fields{ff});
            end
            handles.tool.setImage(Current,[],[],[],[],Mask);
        end

        function RmAppData(app, varargin)
            % RMAPPDATA

            for k=1:nargin; rmappdata(0, varargin{k}); end
        end


        function SetAppData(app, varargin)
            %SETAPPDATA - Fixed for App Designer
            % Handle both cases: with and without app parameter

            if nargin == 1
                % Only app parameter provided, do nothing
                return;
            end

            % Start from the second argument (skip app)
            for k = 2:nargin
                varName = inputname(k);
                if ~isempty(varName)
                    setappdata(0, varName, varargin{k-1});
                else
                    % Fallback for unnamed variables
                    setappdata(0, ['var_' num2str(k-1)], varargin{k-1});
                end
            end
        end

        function SimfunGUI(app, functionName)
            Model = getappdata(0,'Model');
            SimfunGUI = str2func(functionName);
            SimfunGUI(Model);
        end

        function UpdateOptions(app, Sim, Prot, FitOpt)
            % UPDATE OPTIONS

            h = findobj('Tag','OptionsGUI');
            if ~isempty(h)
                OptionsGUIhandles = guidata(h);
                set(OptionsGUIhandles.SimFileName,   'String',  Sim.FileName);
                set(OptionsGUIhandles.ProtFileName,  'String',  Prot.FileName);
                set(OptionsGUIhandles.FitOptFileName,'String',  FitOpt.FileName);
            end
        end

        function addModelMenu(app, hObject, eventdata, handles)
            % Display all the options in the popupmenu
            [MethodList, pathmodels] = sct_tools_ls([handles.ModelDir filesep '*.m'],0,0,2,1);
            pathmodels = cellfun(@(x) strrep(x,[handles.ModelDir filesep],''), pathmodels,'UniformOutput',false);
            if isdeployed
                [MethodList, pathmodels] = qMRLab_static_Models;
            end
            SetAppData(app, MethodList)
            maxlength = max(cellfun(@length,MethodList))+4;
            maxlengthpath = max(cellfun(@length,pathmodels))+2;
            for iM=1:length(MethodList), MethodListfull{iM} = sprintf(['%-' num2str(maxlength) 's%-' num2str(maxlengthpath) 's'],MethodList{iM},['(' strrep(pathmodels{iM},[handles.ModelDir filesep],'') ')']); end
            set(handles.MethodSelection,'String',MethodListfull);
            set(handles.MethodSelection,'FontName','FixedWidth')
            set(handles.MethodSelection,'FontWeight','bold')
        end

        function txt = dataCursorUpdateFcn(app, h_PointDataTip, event_obj, handles)
            % Customizes text of data tips
            pos = get(event_obj,'Position');
            data = handles.tool.getCurrentImageSlice;

            SourceFields = cellstr(get(handles.SourcePop,'String'));
            Source = SourceFields{get(handles.SourcePop,'Value')};

            sliceNum = handles.tool.getCurrentSlice;

            txt = {['Source: ', Source],...
                ['[X,Y]: ', '[', num2str(pos(1)), ',', num2str(pos(2)), ']'],...
                ['Slice: ', num2str(sliceNum)],...
                ['Value: ', num2str(data(pos(2), pos(1)))]};
        end

        function ed_call(app, varargin)
            % Callback for the histogram edit box.
            [h_edit_bin,h_cell] = varargin{[1,3]};
            h_hist = h_cell{1};
            h_slider_bin = h_cell{2};

            for ic=1:length(h_hist)
                h_hist(ic).BinWidth = max(eps,str2double(h_edit_bin.String));
            end
            h_slider_bin.Value = round(str2double(h_edit_bin.String));
        end

        function minmax_call(app, varargin)
            % Callback for the histogram bin bounds recalculate box.
            h_cell = varargin{3};
            h_hist = h_cell{1};
            h_min = h_cell{2};
            h_max = h_cell{3};

            % Mask data out of range of min-max
            minVal = str2double(h_min.String);
            maxVal = max(minVal,str2double(h_max.String));

            for ic = 1:length(h_hist)
                h_hist(ic).BinLimits = [minVal maxVal];
            end
        end

        function norm_call(app, varargin)
            % Callback for the histogram edit box.
            [h_popup_norm,h_cell] = varargin{[1,3]};
            h_hist = h_cell{1};
            h_ylabel = h_cell{2};

            menu_status = h_popup_norm.String{h_popup_norm.Value};

            for ic=1:length(h_hist)
                switch menu_status
                    case 'Count'
                        h_hist(ic).Normalization = 'count';
                    case 'Cumulative count'
                        h_hist(ic).Normalization = 'cumcount';
                    case 'Probability'
                        h_hist(ic).Normalization = 'probability';
                    case 'PDF'
                        h_hist(ic).Normalization = 'pdf';
                    case 'CDF'
                        h_hist(ic).Normalization = 'cdf';
                end
            end
            h_ylabel.String = menu_status;
        end

        function hh = plotfit(app, handles, vox)
            Model = GetAppData(app, 'Model');
            % Get data
            data =  getappdata(0,'Data'); data=data.(class(getappdata(0,'Model')));
            S = [size(data.(Model.MRIinputs{1}),1) size(data.(Model.MRIinputs{1}),2) size(data.(Model.MRIinputs{1}),3)];
            Data = handles.tool.getImage(0);
            Scurrent = [size(Data,1) size(Data,2) size(Data,3)];
            datafields = get(handles.SourcePop,'String');

            if sum(S)==0
                helpdlg(['Specify a ' Model.MRIinputs{1} ' file in the filebrowser'])
            elseif ~isequal(Scurrent(1:3), S(1:3))
                Sstr = sprintf('%ix',S);
                Scurstr = sprintf('%ix',Scurrent);
                helpdlg([Model.MRIinputs{1} ' file (' Sstr(1:end-1) ') in the filebrowser is inconsistent with ' datafields{get(handles.SourcePop,'Value')} ' in the viewer (' Scurstr(1:end-1) '). Load corresponding ' Model.MRIinputs{1} '.'])
                return;
            end

            Model.sanityCheck(data);

            for ipix = 1:length(vox)

                for ii=1:length(Model.MRIinputs)
                    if isfield(data,(Model.MRIinputs{ii})) && ~isempty(data.(Model.MRIinputs{ii}))
                        voltmp = reshape2D(data.(Model.MRIinputs{ii}),4);
                        datasqueeze.(Model.MRIinputs{ii}) = nanmean(voltmp(:,vox{ipix}),2);
                    end
                end
                if isfield(datasqueeze,'Mask'), datasqueeze.Mask = 1; end


                % Create axe
                hh = 68;
                figure(hh)
                h = findobj(hh,'Style','checkbox','String','hold plot in order to compare voxels');
                if ipix==1 && (isempty(h) || ~get(h,'Value'))  % If a data fit check has already been run OR do not hold plot,
                    clf(hh)        % clear the previous data from the figure plot
                    uicontrol('Style','checkbox','String','hold plot in order to compare voxels','Value',0,'Position',[0 0 210 20]);
                end

                haxes = get(hh,'children'); haxes = haxes(strcmp(get(haxes,'Type'),'axes'));

                if ~isempty(haxes)
                    % turn gray old plots
                    for h=1:length(haxes) %might have subplots
                        haxe = get(haxes(h),'children');
                        set(haxe,'Color',[0.8 0.8 0.8]);
                        hAnnotation = get(haxe,'Annotation');
                        % remove their legends
                        for ih=1:length(hAnnotation)
                            if iscell(hAnnotation), hAnnot = hAnnotation{ih}; else hAnnot = hAnnotation; end
                            hLegendEntry = get(hAnnot,'LegendInformation');
                            set(hLegendEntry,'IconDisplayStyle','off');
                        end
                    end
                end
                hold on;

                % Do the fitting
                if ~ismethod(Model,'plotModel'), warndlg('No plotting methods in this model'); return; end
                Fit = Model.fit(datasqueeze) % Display fitting results in command window
                Model.plotModel(Fit,datasqueeze);

                % update legend
                if ~moxunit_util_platform_is_octave
                    legend('Location','best')
                end

            end
        end

        function sl_call(app, varargin)
            % Histogram GUI callbacks

            % Callback for the histogram slider.
            [h_slider_bin,h_cell] = varargin{[1,3]};
            h_hist = h_cell{1};
            h_edit_bin = h_cell{2};
            for ic = 1:length(h_hist)
                h_hist(ic).BinWidth = h_slider_bin.Value;
            end
            h_edit_bin.String = h_slider_bin.Value;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function qMRLab_OpeningFcn(app, varargin)
            % --- Executes just before qMRLab is made visible.

            % Ensure that the app appears on screen when run
            movegui(app.qMRILab, 'onscreen');

            % Set light/dark theme
            applyTheme(app);

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            if max(strcmp(varargin,'wait')), wait=true; varargin(strcmp(varargin,'wait'))=[]; else wait=false; end
            if ~isfield(handles,'opened') % qMRI already opened?
                warning('off','all');
                % Add qMRLab to path
                qMRLabDir = fileparts(which(mfilename()));
                addpath(genpath(qMRLabDir));

                % Do not let this break anything if things go wrong.
                try
                    GUI_animation;
                    cur_ver = qMRLabVer;
                catch
                    cur_ver = qMRLabVer;
                    fprintf('qMRLab version: v%d.%d.%d \n',cur_ver(1),cur_ver(2),cur_ver(3));
                end

                try
                    [verStatus] = versionChecker;
                catch
                    verStatus = [];
                end

                % Display version under qMRLab text
                set(handles.text_version_check, 'String',sprintf('v%d.%d.%d',cur_ver(1),cur_ver(2),cur_ver(3)));

                % Handle new version message
                % varstatus is empty unless there is a new release.
                if isempty(verStatus)
                    set(handles.upgrade_message, 'Visible','off');
                else
                    set(handles.upgrade_message, 'Visible','on');
                    set(handles.upgrade_message, 'String',sprintf('Upgrade to v%d.%d.%d',verStatus(1),verStatus(2),verStatus(3)));
                end

                handles.opened = 1;
                % startup;
                qMRLabDir = fileparts(which(mfilename()));
                addpath(genpath(qMRLabDir));
                if isdeployed
                    handles.Default = fullfile(qMRLabDir,'DefaultMethod.mat');
                else
                    handles.Default = fullfile(qMRLabDir,'src','Common','Parameters','DefaultMethod.mat');
                    if isempty(getenv('ISAZURE')) || ~str2double(getenv('ISAZURE'))
                        ISAZURE=false;
                    else
                        ISAZURE=true;
                    end
                    if ~ISAZURE
                        if ~license('test', 'Optimization_Toolbox'), error('Optimization Toolbox is not installed on your system: most qMR models won''t fit. Please consider installing <a href="matlab:matlab.internal.language.introspective.showAddon(''OP'');">Optimization Toolbox</a> if you want to use qMRLab in MATLAB.'); end
                        if ~license('test', 'Image_Toolbox'), warning('Image Toolbox is not installed: ROI Analysis tool not available in the GUI. Consider installing <a href="matlab:matlab.internal.language.introspective.showAddon(''IP'');">Image Processing Toolbox</a>'); end
                    end
                end
                handles.CurrentData = [];
                handles.dcm_obj = [];
                MethodList = {}; SetAppData(app, MethodList);
                guidata(hObject, handles);


                % SET WINDOW AND PANELS
                movegui(gcf,'center')
                CurrentPos = get(gcf, 'Position');
                NewPos     = CurrentPos;
                NewPos(1)  = CurrentPos(1) - 40;
                set(gcf, 'Position', NewPos);
                if ispc , set(findobj(handles.FitResultsPlotPanel,'Type','uicontrol'),'FontSize',7); end % everything is bigger on windows or linux

                % Create viewer
                handles.tool = imtool3D(0,[0.25 0 .75 1],handles.FitResultsPlotPanel);
                H = handles.tool.getHandles;
                set(H.Tools.ViewPlane,'Visible','off')
                set(H.Tools.maskStats,'Visible','off')

                % Fill Menu with models
                handles.ModelDir = [qMRLabDir filesep 'src/Models'];
                guidata(hObject, handles);
                addModelMenu(app, hObject, eventdata, handles);
                % Determine the initial method first
                if exist(handles.Default,'file')
                    load(handles.Default);
                else
                    Method = 'inversion_recovery';
                end

                FileBrowserList = GetAppData(app, 'FileBrowserList');
                if isempty(FileBrowserList)
                    % Create empty structure for FileBrowserList
                    FileBrowserList = struct();
                    SetAppData(app, FileBrowserList);
                end

                % Only create FileBrowser for the current method
                if ~isfield(FileBrowserList, Method) || isempty(FileBrowserList.(Method))
                    Modelfun = str2func(Method);
                    Model = Modelfun();
                    FileBrowserList.(Method) = MethodBrowser(app.FitDataFileBrowserPanel, Model);
                    FileBrowserList.(Method).Visible('off');
                    SetAppData(app, FileBrowserList);
                end
            else
                Method = class(GetAppData(app, 'Model'));
            end
            % LOAD INPUT
            if ~isempty(varargin)
                Model = varargin{1};
                SetAppData(app, Model);
                Method = class(Model);
                FileBrowserList = GetAppData(app, 'FileBrowserList');
                if length(varargin)>1
                    data=varargin{2};
                    for ff=fieldnames(data)'
                        FileBrowserList(strcmp({FileBrowserList.MethodID},Method)).setFileName(ff{1}, data.(ff{1}))
                    end
                end
            end

            % Set Menu to method
            MethodList = getappdata(0, 'MethodList');
            indice = find(strcmp(Method,MethodList));
            set(handles.MethodSelection, 'Value', indice);


            MethodMenu(app, hObject, eventdata, handles, Method);

            % Wait if output
            if wait
                uiwait(hObject)
            end


            % View first file
            if length(varargin)>1
                butobj = FileBrowserList(strcmp({FileBrowserList.MethodID},Method)).ItemsList(1);
                butobj.ViewBtn_callback(butobj,[],[],handles)
            end

            set(handles.text_doc_model, 'String',['Visit ' Method ' documentation']);
            warning('on','all');
        end

        % Value changed function: CursorBtn
        function CursorBtn_Callback(app, event)

            % Value changed function: CursorBtn
            % CURSOR

            % Get current button state directly from app.CursorBtn
            buttonState = app.CursorBtn.Value;

            if buttonState == 1
                % Button is pressed/down - ENABLE cursor mode
                try
                    % Get handles from app data or figure
                    handles = guidata(app.qMRILab);

                    if ~isempty(handles.tool)
                        H = handles.tool.getHandles();
                        fig = H.fig;

                        % Create new datacursor object
                        dcm_obj = datacursormode(fig);

                        % Configure it
                        set(dcm_obj, 'Enable', 'on');
                        set(dcm_obj, 'SnapToDataVertex', 'on');
                        set(dcm_obj, 'DisplayStyle', 'datatip');
                        set(dcm_obj, 'UpdateFcn', {@app.dataCursorUpdateFcn, handles});

                        % Store it in multiple places for persistence
                        handles.dcm_obj = dcm_obj;
                        app.qMRILab.UserData.dcm_obj = dcm_obj;
                        setappdata(0, 'dcm_obj', dcm_obj);

                        % Update button text directly using app.CursorBtn
                        app.CursorBtn.Text = 'Selecting...';

                        % Save handles
                        guidata(app.qMRILab, handles);

                        % Bring figure to front
                        figure(fig);

                        disp('Cursor mode: ON');
                    else
                        errordlg('Image viewer not initialized.');
                        app.CursorBtn.Value = 0; % Reset button
                    end

                catch ME
                    errordlg(['Error: ' ME.message]);
                    app.CursorBtn.Value = 0; % Reset button
                end
            else
                % Button is released/up - DISABLE cursor mode
                try
                    % Get handles
                    handles = guidata(app.qMRILab);

                    % Check all possible storage locations
                    dcm_to_disable = [];

                    if isfield(handles, 'dcm_obj')
                        dcm_to_disable = handles.dcm_obj;
                    elseif isfield(app.qMRILab.UserData, 'dcm_obj')
                        dcm_to_disable = app.qMRILab.UserData.dcm_obj;
                    elseif ~isempty(getappdata(0, 'dcm_obj'))
                        dcm_to_disable = getappdata(0, 'dcm_obj');
                    end

                    if ~isempty(dcm_to_disable)
                        set(dcm_to_disable, 'Enable', 'off');
                        disp('Cursor mode: OFF');
                    end

                    % Update button text directly
                    app.CursorBtn.Text = 'Select';

                catch ME
                    % If error, just update button text
                    app.CursorBtn.Text = 'Select';
                end
            end


        end

        % Button pushed function: DefaultMethodBtn
        function DefaultMethodBtn_Callback(app, event)
            % SET DEFAULT METHODSELECTION

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            Method = GetMethod(handles);
            setappdata(0, 'Method', Method);
            save(handles.Default,'Method');
        end

        % Callback function
        function FitGO_Callback(app, event)
            % FITDATA GO

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            Method = GetMethod(handles);
            setappdata(0, 'Method', Method);
            FitGo_FitData(app, hObject, eventdata, handles);
            % The counterSfMiss variable is assigned by the GetSf.m function
            % to keep track of how many times a warning has been printed.
            % After fit has been completed, we can remove this from the base
            % workspace to avoid confusion.
            if ~evalin('base','exist(''counterSfMiss'')')
                evalin('base','clear(''counterSfMiss'')');
            end
        end

        % Callback function
        function FitResultsLoad_Callback(app, event)
            % FITRESULTSLOAD

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            [FileName,PathName] = uigetfile({'*FitResults*.mat;*.qmrlab.mat;*.mat'},'FitResults.mat');
            if PathName == 0, return; end
            set(handles.CurrentFitId,'String',FileName);
            FitResults = load(fullfile(PathName,FileName));
            if isfield(FitResults,'Protocol')
                Prot   =  FitResults.Protocol;
            else
                Prot   =  FitResults.Prot;
            end
            if isfield(FitResults,'FitOpt'), FitOpt =  FitResults.FitOpt; SetAppData(app, FitResults, Prot, FitOpt); Method = FitResults.Protocol.Method; end
            if isfield(FitResults,'Model')
                Method = FitResults.Model.ModelName;
                Model = qMRloadObj(FitResults.Model);
                SetAppData(app, FitResults,Model);
            end

            % find model value in the method menu list
            methods = sct_tools_ls([handles.ModelDir filesep '*.m'], 0,0,2,1);
            val = find(strcmp(methods,Method));
            set(handles.MethodSelection,'Value',val)

            MethodMenu(app, hObject, eventdata, handles,Method)
            handles = guidata(hObject); % update handle
            FileBrowserList = GetAppData(app, 'FileBrowserList');
            % if isfield(FitResults,'WD'), FileBrowserList.setWD(FitResults.WD); end
            % if isfield(FitResults,'StudyID'), FileBrowserList.setStudyID(FitResults.StudyID); end
            % if isfield(FitResults,'Files'),
            %     for ifile = fieldnames(FitResults.Files)'
            %         FileBrowserList.setFileName(ifile{1},FitResults.Files.(ifile{1}))
            %     end
            % end

            SetAppData(app, FileBrowserList);
            handles.CurrentData = FitResults;
            guidata(hObject,handles);
            DrawPlot(handles);
        end

        % Callback function
        function FitResultsSave_Callback(app, event)
            % FITRESULTSSAVE

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            FitResults = GetAppData(app, 'FitResults');
            [FileName,PathName] = uiputfile('*.mat');
            if PathName == 0, return; end
            save(fullfile(PathName,FileName),'-struct','FitResults');
            set(handles.CurrentFitId,'String',FileName);
        end

        % Button pushed function: Histogram
        function Histogram_Callback(app, event)
            % HISTOGRAM FIG

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % unselect button to prevent activation with spacebar
            set(hObject, 'Enable', 'off');
            drawnow;
            set(hObject, 'Enable', 'on');
            % Plot figure
            f=figure('Position', [100 100 700 400], 'Resize', 'Off','Name','Histogram');

            Map = handles.tool.getImage;
            MatlabVer = version;
            if str2double(MatlabVer(1))<8 || (str2double(MatlabVer(1))==8 && str2double(MatlabVer(3))<4)
                Maskall = uint8(handles.tool.getMask);
            else
                Maskall = handles.tool.getMask(1);
                h_plot = subplot(1,2,2); % Use subplot to give space for GUI elements
                h_plot.OuterPosition = [0.3 0 0.7 1.0];
            end

            % loop over mask
            values = unique(Maskall(Maskall>0))';
            if isempty(values), values = 0; end
            for ic = 1:length(values)
                Selected = values(ic);
                Mask = Maskall == Selected;

                ii = find(Mask);
                nVox = length(ii);
                data = reshape(Map(ii),1,nVox);

                % Matlab < R2014b
                MatlabVer = version;
                if str2double(MatlabVer(1))<8 || (str2double(MatlabVer(1))==8 && str2double(MatlabVer(3))<4)
                    defaultNumBins = max(5,round(length(data)/100));
                    hist(data, defaultNumBins);
                    % Label axes
                    SourceFields = cellstr(get(handles.SourcePop,'String'));
                    Source = SourceFields{get(handles.SourcePop,'Value')};
                    xlabel(Source);
                    ylabel('Counts');
                    return;
                end

                % Matlab >= R2014b
                hold on
                h_hist(ic)=histogram(data);
                BinWidth(ic) = h_hist.BinWidth;

                hold off
                Color = handles.tool.getMaskColor;
                set(h_hist(ic),'FaceColor',Color(Selected+1,:),'FaceAlpha',0.3)
            end

            % set BinWidth
            BinWidth = median(BinWidth);
            for ic = 1:length(h_hist)
                h_hist(ic).BinWidth = BinWidth;
            end

            % Label axes
            SourceFields = cellstr(get(handles.SourcePop,'String'));
            Source = SourceFields{get(handles.SourcePop,'Value')};
            xlabel(Source);
            h_ylabel = ylabel('Counts');

            % No. of bins GUI objects
            h_text_bin = uicontrol(f,'Style','text',...
                'String', 'Width of bins:',...
                'FontSize', 14,...
                'Position',[5 20+300 140 34]);
            h_edit_bin = uicontrol(f,'Style','edit',...
                'String', BinWidth,...
                'FontSize', 14,...
                'Position',[135 25+300 70 34]);
            h_slider_bin = uicontrol(f,'Style','slider',...
                'Min',BinWidth/10,'Max',BinWidth*10,'Value',BinWidth,...
                'SliderStep',[1/(100-1) 1/(100-1)],...
                'Position',[205 26+300 10 30],...
                'Callback',{@app.sl_call,{h_hist h_edit_bin}});
            h_edit_bin.Callback = {@app.ed_call,{h_hist h_slider_bin}};

            % Min-Max GUI objects
            h_text_min = uicontrol(f,'Style','text',...
                'String', 'Min',...
                'FontSize', 14,...
                'Position',[0 20+200 140 34]);
            BinLimits = cat(1,h_hist.BinLimits);
            h_edit_min = uicontrol(f,'Style','edit',...
                'String', min(BinLimits(:,1)),...
                'FontSize', 14,...
                'Position', [35 20+180 70 34]);
            h_text_max = uicontrol(f,'Style','text',...
                'String', 'Max',...
                'FontSize', 14,...
                'Position',[130 20+200 40 34]);
            h_edit_max = uicontrol(f,'Style','edit',...
                'String', max(BinLimits(:,2)),...
                'FontSize', 14,...
                'Position', [116 20+180 70 34]);
            h_button_minmax = uicontrol(f,'Style','pushbutton',...
                'String', 'Recalculate',...
                'FontSize', 14,...
                'Position', [65 20+140 100 34],...
                'Callback',{@app.minmax_call,{h_hist h_edit_min h_edit_max data}});

            % Normalization GUI objects
            h_text_min = uicontrol(f,'Style','text',...
                'String', 'Normalization mode',...
                'FontSize', 14,...
                'Position',[30 20+40 180 34]);
            h_popup_norm = uicontrol(f,'Style','popupmenu',...
                'String', {'Count',...
                'Cumulative count',...
                'Probability',...
                'PDF',...
                'CDF'},...
                'FontSize', 14,...
                'Position', [30 20+20 180 34],...
                'Callback',{@app.norm_call,{h_hist h_ylabel}});
        end

        % Value changed function: MethodSelection
        function MethodSelection_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            Method = GetMethod(handles);
            MethodMenu(app, hObject,eventdata,handles,Method);
            set(handles.text_doc_model, 'String',['Visit ' Method ' documentation']);
        end

        % Button pushed function: OpenOptionsPanel
        function OpenOptionsPanel_Callback(app, event)
            % OPEN OPTIONS

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            Method = GetAppData(app, 'Method');
            Model = getappdata(0,'Model');
            Custom_OptionsGUI(Model, gcf);
        end

        % Value changed function: SourcePop
        function SourcePop_Callback(app, event)
            % SOURCE

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % unselect button to prevent activation with spacebar
            set(hObject, 'Enable', 'off');
            drawnow;
            set(hObject, 'Enable', 'on');

            handles.tool.setNvol(get(handles.SourcePop,'Value'));
        end

        % Button pushed function: Stats
        function Stats_Callback(app, event)
            % STATS Table

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            set(hObject, 'Enable', 'off');
            drawnow;
            set(hObject, 'Enable', 'on');

            I = handles.tool.getImage(1);
            Iraw = handles.CurrentData;
            fields = setdiff(Iraw.fields,'Mask','stable')';
            Maskall = handles.tool.getMask(1);
            Color = handles.tool.getMaskColor;
            StatsGUI(I,Maskall, fields, Color);
        end

        % Button pushed function: ViewDataFit
        function ViewDataFit_Callback(app, event)

            % PLOT DATA FIT

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % unselect button to prevent activation with spacebar
            set(hObject, 'Enable', 'off');
            drawnow;
            set(hObject, 'Enable', 'on');

            % First check if cursor mode is even enabled
            if ~isfield(handles, 'dcm_obj') || isempty(handles.dcm_obj)
                helpdlg('First click the "Select" button to enable cursor mode, then click on a pixel');
                return;
            end

            % Try to get cursor info
            try
                % Check if datacursor is enabled
                if ~strcmp(get(handles.dcm_obj, 'Enable'), 'on')
                    helpdlg('Cursor mode is not enabled. Click the "Select" button first.');
                    return;
                end

                % Try to get cursor info
                cursorInfo = getCursorInfo(handles.dcm_obj);

                if isempty(cursorInfo)
                    helpdlg('No cursor selected. Click on a pixel in the image first.');
                    return;
                end

                % Process the cursor info
                info_dcm_all = getCursorInfo(handles.dcm_obj);
                for ipix = 1:length(info_dcm_all)
                    info_dcm = info_dcm_all(ipix);
                    x = info_dcm.Position(1);
                    y = info_dcm.Position(2);
                    z = handles.tool.getCurrentSlice;
                    S = handles.tool.getImageSize;
                    vox{ipix} = sub2ind(S,y,x,z);
                end
                hh = plotfit(app, handles,vox);
                if ~isempty(hh)
                    set(hh,'Name',['Fitting results of voxel [' num2str([info_dcm.Position(1) info_dcm.Position(2) z]) ']'],'NumberTitle','off');
                    set(hh,'Color',[.94 .94 .94])
                end

            catch ME
                % If getCursorInfo fails, the datacursor might not be properly set up
                helpdlg('Error getting cursor info. Make sure you: 1) Click the "Select" button, 2) Click on a pixel in the image');
                disp(['Error: ' ME.message]);
            end

        end

        % Value changed function: ViewPop
        function ViewPop_Callback(app, event)
            % VIEW

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % unselect button to prevent activation with spacebar
            set(hObject, 'Enable', 'off');
            drawnow;
            set(hObject, 'Enable', 'on');

            UpdateSlice(handles)
            View = get(handles.ViewPop,'String');
            if ~iscell(View), View = {View}; end
            handles.tool.setviewplane(View{get(handles.ViewPop,'Value')})
        end

        % Button pushed function: ViewROIFit
        function ViewROIFit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % unselect button to prevent activation with spacebar
            set(hObject, 'Enable', 'off');
            drawnow;
            set(hObject, 'Enable', 'on');

            Mask = handles.tool.getMask();
            if isempty(Mask) || ~any(Mask(:))
                helpdlg('Draw a mask for current label using the brush tools')
                return;
            end

            vox{1} = find(Mask);
            hh = plotfit(app, handles,vox);
            if ~isempty(hh)
                set(hh,'Name',['Fitting results in current label #' num2str(handles.tool.getmaskSelected())],'NumberTitle','off');
                C = handles.tool.getMaskColor();
                set(hh,'Color',[1 1 1]*.8+.2*C(handles.tool.getmaskSelected()+1,:))
            end
        end

        % Button pushed function: Viewer
        function Viewer_Callback(app, event)
            % OPEN VIEWER

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % unselect button to prevent activation with spacebar
            set(hObject, 'Enable', 'off');
            drawnow;
            set(hObject, 'Enable', 'on');

            I.img = handles.tool.getImage(1);
            I.label = cellstr(get(handles.SourcePop,'String'));
            Mask = handles.tool.getMask(1);
            if isfield(handles.CurrentData,'hdr')
                I.hdr = handles.CurrentData.hdr;

                tool = imtool3D_nii_3planes(I,Mask);
            else

                tool = imtool3D_3planes(I.img,Mask);

                for ii=1:3, tool(ii).setlabel(I.label); end
            end
            clims = handles.tool.getClimits;
            for ii=1:3
                tool(ii).setNvol(handles.tool.getNvol);
                tool(ii).setClimits(clims);
            end
        end

        % Close request function: qMRILab
        function qMRLab_CloseRequestFcn(app, event)
            % Executes when user attempts to close qMRLab.

            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            if isequal(get(hObject, 'waitstatus'), 'waiting')
                % The GUI is still in UIWAIT, us UIRESUME
                uiresume(hObject);
            else
                AppData = getappdata(0);
                Fields = fieldnames(AppData);
                for k=1:length(Fields)
                    rmappdata(0, Fields{k});
                end
            end
            % The GUI is no longer waiting, just close it
            delete(hObject);
            h = findobj('Tag','OptionsGUI');
            delete(findobj('Tag','Simu'))
            delete(h);
            wh=findall(0,'tag','TMWWaitbar');
            delete(wh);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.qMRILab = uifigure('Visible', 'off');
            colormap(app.qMRILab, 'parula');
            app.qMRILab.Name = 'qMRLab';
            app.qMRILab.CloseRequestFcn = createCallbackFcn(app, @qMRLab_CloseRequestFcn, true);
            app.qMRILab.Tag = 'qMRILab';
            app.text_version_check = uilabel(app.qMRILab);
            app.text_version_check.Tag = 'text_version_check';
            app.text_version_check.Text = 'vx.y.z';
            app.FitDataPanel = uipanel(app.qMRILab);
            app.FitDataPanel.Title = 'Fit qMRI Data';
            app.FitDataPanel.Tag = 'FitDataPanel';
            app.FitResultsPlotPanel = uipanel(app.FitDataPanel);
            app.FitResultsPlotPanel.Title = 'Image Viewer';
            app.FitResultsPlotPanel.Tag = 'FitResultsPlotPanel';
            app.Histogram = uibutton(app.FitResultsPlotPanel, 'push');
            app.Histogram.ButtonPushedFcn = createCallbackFcn(app, @Histogram_Callback, true);
            app.Histogram.Tag = 'Histogram';
            app.Histogram.Text = 'Histogram';
            app.ViewPop = uidropdown(app.FitResultsPlotPanel);
            app.ViewPop.Items = {'Axial'};
            app.ViewPop.ValueChangedFcn = createCallbackFcn(app, @ViewPop_Callback, true);
            app.ViewPop.Tag = 'ViewPop';
            app.ViewPop.Value = 'Axial';
            app.SourcePop = uidropdown(app.FitResultsPlotPanel);
            app.SourcePop.Items = {};
            app.SourcePop.ValueChangedFcn = createCallbackFcn(app, @SourcePop_Callback, true);
            app.SourcePop.Tag = 'SourcePop';
            app.SourcePop.Value = {};
            app.CursorBtn = uibutton(app.FitResultsPlotPanel, 'state');
            app.CursorBtn.ValueChangedFcn = createCallbackFcn(app, @CursorBtn_Callback, true);
            app.CursorBtn.Tag = 'CursorBtn';
            app.CursorBtn.Text = 'Select';
            app.ViewDataFit = uibutton(app.FitResultsPlotPanel, 'push');
            app.ViewDataFit.ButtonPushedFcn = createCallbackFcn(app, @ViewDataFit_Callback, true);
            app.ViewDataFit.Tag = 'ViewDataFit';
            app.ViewDataFit.Text = 'Voxel fit';
            app.text72 = uilabel(app.FitResultsPlotPanel);
            app.text72.Tag = 'text72';
            app.text72.Text = 'Cursor';
            app.Viewer = uibutton(app.FitResultsPlotPanel, 'push');
            app.Viewer.ButtonPushedFcn = createCallbackFcn(app, @Viewer_Callback, true);
            app.Viewer.Tag = 'Viewer';
            app.Viewer.Text = '3D viewer';
            app.text80 = uilabel(app.FitResultsPlotPanel);
            app.text80.Tag = 'text80';
            app.text80.Text = 'Quality Assurance';
            app.txt_OrientL = uilabel(app.FitResultsPlotPanel);
            app.txt_OrientL.Tag = 'txt_OrientL';
            app.txt_OrientL.Text = 'L';
            app.txt_OrientR = uilabel(app.FitResultsPlotPanel);
            app.txt_OrientR.Tag = 'txt_OrientR';
            app.txt_OrientR.Text = 'R';
            app.txt_OrientI = uilabel(app.FitResultsPlotPanel);
            app.txt_OrientI.Tag = 'txt_OrientI';
            app.txt_OrientI.Text = 'I';
            app.txt_OrientS = uilabel(app.FitResultsPlotPanel);
            app.txt_OrientS.Tag = 'txt_OrientS';
            app.txt_OrientS.Text = 'S';
            app.Stats = uibutton(app.FitResultsPlotPanel, 'push');
            app.Stats.ButtonPushedFcn = createCallbackFcn(app, @Stats_Callback, true);
            app.Stats.Tag = 'Stats';
            app.Stats.Text = 'Statistics';
            app.ViewROIFit = uibutton(app.FitResultsPlotPanel, 'push');
            app.ViewROIFit.ButtonPushedFcn = createCallbackFcn(app, @ViewROIFit_Callback, true);
            app.ViewROIFit.Tag = 'ViewROIFit';
            app.ViewROIFit.Text = 'ROI fit';
            app.text80_2 = uilabel(app.FitResultsPlotPanel);
            app.text80_2.Tag = 'text80';
            app.text80_2.Text = 'Volume';
            app.FitDataFileBrowserPanel = uipanel(app.FitDataPanel);
            app.FitDataFileBrowserPanel.Title = 'Datasets';
            app.FitDataFileBrowserPanel.Tag = 'FitDataFileBrowserPanel';
            app.text53 = uilabel(app.FitDataPanel);
            app.text53.Tag = 'text53';
            app.text53.Text = 'Current file :';
            app.CurrentFitId = uilabel(app.FitDataPanel);
            app.CurrentFitId.Tag = 'CurrentFitId';
            app.CurrentFitId.Text = '';
            app.text_doc_model = uilabel(app.FitDataPanel);
            app.text_doc_model.Tag = 'text_doc_model';
            app.text_doc_model.Text = 'Click here for user documentation';
            app.SimPanel = uipanel(app.qMRILab);
            app.SimPanel.Title = 'Simulation tools';
            app.SimPanel.Tag = 'SimPanel';
            app.SimCurveBtn = uibutton(app.SimPanel, 'push');
            app.SimCurveBtn.Tag = 'SimCurveBtn';
            app.SimCurveBtn.Text = 'Single Voxel Curve';
            app.SimVaryBtn = uibutton(app.SimPanel, 'push');
            app.SimVaryBtn.Tag = 'SimVaryBtn';
            app.SimVaryBtn.Text = 'Sensitivity Analysis';
            app.SimRndBtn = uibutton(app.SimPanel, 'push');
            app.SimRndBtn.Tag = 'SimRndBtn';
            app.SimRndBtn.Text = 'Multi Voxel Distribution';
            app.SimSave = uibutton(app.SimPanel, 'push');
            app.SimSave.Tag = 'SimSave';
            app.SimSave.Text = 'Save Results';
            app.SimLoad = uibutton(app.SimPanel, 'push');
            app.SimLoad.Tag = 'SimLoad';
            app.SimLoad.Text = 'Load Results';
            app.SimGO = uibutton(app.SimPanel, 'push');
            app.SimGO.Tag = 'SimGO';
            app.SimGO.Text = 'Simulate data';
            app.uipanel37 = uipanel(app.qMRILab);
            app.uipanel37.Tag = 'uipanel37';
            app.FitResultsLoad = uibutton(app.uipanel37, 'push');
            app.FitResultsLoad.Tag = 'FitResultsLoad';
            app.FitResultsLoad.Text = 'Load Results';
            app.FitResultsSave = uibutton(app.uipanel37, 'push');
            app.FitResultsSave.Tag = 'FitResultsSave';
            app.FitResultsSave.Text = 'Save Results';
            app.FitGO = uibutton(app.uipanel37, 'push');
            app.FitGO.Tag = 'FitGO';
            app.FitGO.Text = 'Fit data';
            app.DefaultMethodBtn = uibutton(app.qMRILab, 'push');
            app.DefaultMethodBtn.ButtonPushedFcn = createCallbackFcn(app, @DefaultMethodBtn_Callback, true);
            app.DefaultMethodBtn.Tag = 'DefaultMethodBtn';
            app.DefaultMethodBtn.Text = 'Set Default';
            app.OpenOptionsPanel = uibutton(app.qMRILab, 'push');
            app.OpenOptionsPanel.ButtonPushedFcn = createCallbackFcn(app, @OpenOptionsPanel_Callback, true);
            app.OpenOptionsPanel.Tag = 'OpenOptionsPanel';
            app.OpenOptionsPanel.Text = 'Open Options Panel';
            app.MethodSelection = uidropdown(app.qMRILab);
            app.MethodSelection.Items = {'modelname'};
            app.MethodSelection.ValueChangedFcn = createCallbackFcn(app, @MethodSelection_Callback, true);
            app.MethodSelection.Tag = 'MethodSelection';
            app.MethodSelection.Value = 'modelname';
            app.upgrade_message = uilabel(app.qMRILab);
            app.upgrade_message.Tag = 'upgrade_message';
            app.upgrade_message.Text = 'Upgrade to v2.5.0';
            app.Image = uiimage(app.qMRILab);
            app.Image.Tag = 'MainLogo';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            % Show the figure after all components are created
            app.qMRILab.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qMRLab(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.qMRILab)

                % Execute the startup function
                runStartupFcn(app, @(app)qMRLab_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.qMRILab)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.qMRILab)
        end
    end
end